classdef RSAR_GUI_Josiah < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ConfigurationTab                matlab.ui.container.Tab
        DeviceSetupPanel                matlab.ui.container.Panel
        DeviceDropDownLabel             matlab.ui.control.Label
        DeviceDropDown                  matlab.ui.control.DropDown
        RadarTypeDropDownLabel          matlab.ui.control.Label
        RadarTypeDropDown               matlab.ui.control.DropDown
        ControllerConnectionPanel       matlab.ui.container.Panel
        ConnecttoControllerButton       matlab.ui.control.Button
        VerticalSpeedmmsEditFieldLabel  matlab.ui.control.Label
        VerticalSpeedmmsEditField       matlab.ui.control.NumericEditField
        HorizontalSpeedmmsEditFieldLabel  matlab.ui.control.Label
        HorizontalSpeedmmsEditField     matlab.ui.control.NumericEditField
        ActuatorConnectionStatusLamp    matlab.ui.control.Lamp
        RotationSpeeddegsEditFieldLabel  matlab.ui.control.Label
        RotationSpeeddegsEditField      matlab.ui.control.NumericEditField
        mmWaveStudioConnectionPanel     matlab.ui.container.Panel
        ConnectToRadarButton            matlab.ui.control.Button
        RadarConnectionStatusLamp       matlab.ui.control.Lamp
        TriggerFrameButton              matlab.ui.control.Button
        ScanNameEditFieldLabel          matlab.ui.control.Label
        ScanNameEditField               matlab.ui.control.EditField
        ScanNameEditFieldLabel_2        matlab.ui.control.Label
        SingleMovementPanel             matlab.ui.container.Panel
        DesiredHorizontalMovementmmEditFieldLabel  matlab.ui.control.Label
        DesiredHorizontalMovementmmEditField  matlab.ui.control.NumericEditField
        DesiredVerticalMovementmmEditFieldLabel  matlab.ui.control.Label
        DesiredVerticalMovementmmEditField  matlab.ui.control.NumericEditField
        SendSingleCommandButton         matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        DesiredRotationMovementdegEditFieldLabel  matlab.ui.control.Label
        DesiredRotationMovementdegEditField  matlab.ui.control.NumericEditField
        RotationSARTab                  matlab.ui.container.Tab
        ContinuousRotationSARScenarioPanel  matlab.ui.container.Panel
        SetupPanel                      matlab.ui.container.Panel
        TimeBeforeCapturingsifTriggerFirstLabel  matlab.ui.control.Label
        WaitTime                        matlab.ui.control.NumericEditField
        RotationSizedegLabel            matlab.ui.control.Label
        RotationSize                    matlab.ui.control.NumericEditField
        VerticalStepSizemmLabel         matlab.ui.control.Label
        VerticalStepSize                matlab.ui.control.NumericEditField
        NumberofVerticalStepsLabel      matlab.ui.control.Label
        nVerticalSteps                  matlab.ui.control.NumericEditField
        LambdaSpacingButton             matlab.ui.control.Button
        StartCapturingRSARCommandButton  matlab.ui.control.Button
        StopCapturingRSARCommandButton  matlab.ui.control.Button
        RectilinearSARTab               matlab.ui.container.Tab
        ContinuousRectilinearSARScenarioPanel  matlab.ui.container.Panel
        SetupPanelRect                  matlab.ui.container.Panel
        TimeBeforeCapturingsifTriggerFirstLabel_2  matlab.ui.control.Label
        WaitTimeRect                    matlab.ui.control.NumericEditField
        HorizontalScanSizemmLabel       matlab.ui.control.Label
        HorizontalScanSize_mm           matlab.ui.control.NumericEditField
        VerticalStepSizemmLabel_2       matlab.ui.control.Label
        VerticalStepSizeRect            matlab.ui.control.NumericEditField
        NumberofVerticalStepsLabel_2    matlab.ui.control.Label
        nVerticalStepsRect              matlab.ui.control.NumericEditField
        LambdaSpacingButtonRect         matlab.ui.control.Button
        StartCapturingRSARCommandButtonRect  matlab.ui.control.Button
        StopCapturingRSARCommandButtonRect  matlab.ui.control.Button
        TwoDirectionScanningRectCheckBox  matlab.ui.control.CheckBox
        WaitBeforeStartRectCheckBox     matlab.ui.control.CheckBox
        RotationHorizontalTab           matlab.ui.container.Tab
        RotationHorizontalScanningScenarioPanel  matlab.ui.container.Panel
        SetupPanel_RH                   matlab.ui.container.Panel
        TimeBeforeCapturingsifTriggerFirstLabel_3  matlab.ui.control.Label
        WaitTime_RH                     matlab.ui.control.NumericEditField
        HorizontalScanSizemmLabel_2     matlab.ui.control.Label
        HorizontalScanSize_mm_RH        matlab.ui.control.NumericEditField
        RotationStepSizedegLabel        matlab.ui.control.Label
        RotationStepSize_deg_RH         matlab.ui.control.NumericEditField
        NumberofRotationStepsLabel      matlab.ui.control.Label
        nRotationSteps_RH               matlab.ui.control.NumericEditField
        StartCapturingCommandButton_RH  matlab.ui.control.Button
        StopCapturingCommandButton_RH   matlab.ui.control.Button
        GesturesTab                     matlab.ui.container.Tab
        GestureScenarioPanel            matlab.ui.container.Panel
        SetupPanelGesture               matlab.ui.container.Panel
        CaptureTimesLabel               matlab.ui.control.Label
        CaptureTime_s                   matlab.ui.control.NumericEditField
        TimeBetweenCapturessLabel       matlab.ui.control.Label
        TimeBetweenCaptures_s           matlab.ui.control.NumericEditField
        BufferTimesLabel                matlab.ui.control.Label
        BufferTime_s                    matlab.ui.control.NumericEditField
        NumberofCapturesLabel           matlab.ui.control.Label
        nGestureCaptures                matlab.ui.control.NumericEditField
        StartCapturingGestureCommandButton  matlab.ui.control.Button
        StopCapturingGestureCommandButtonRect  matlab.ui.control.Button
        WaitBeforeStartCheckBox         matlab.ui.control.CheckBox
        Label                           matlab.ui.control.Label
        EmergencyStopAcuatorButton      matlab.ui.control.Button
        TotalHorizontalMovementmmEditFieldLabel  matlab.ui.control.Label
        TotalHorizontalMovementmmEditField  matlab.ui.control.NumericEditField
        TotalVerticalMovementmmEditFieldLabel  matlab.ui.control.Label
        TotalVerticalMovementmmEditField  matlab.ui.control.NumericEditField
        TotalRotationMovementdegEditFieldLabel  matlab.ui.control.Label
        TotalRotationMovementdegEditField  matlab.ui.control.NumericEditField
        RadarScreenTextAreaLabel        matlab.ui.control.Label
        RadarScreenTextArea             matlab.ui.control.TextArea
        StatusScreenTextAreaLabel       matlab.ui.control.Label
        StatusScreenTextArea            matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        % Private Properties
    end
    
    properties (Access = public)
        % Public Properties
        ScannerCOMPort; % AMC4030 Controller Serial Port
        
        isActuatorConnected = false;
        isTIRadarConnected = false;
        sarScenarioActive = false;
        
        scanName = "scan0";
        
        textMovementString = ' ';
        textRadarStudioErrorString = ' ';
        
        SAR_GUI_Path;
        RSTD_DLL_Path;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnecttoControllerButton
        function ConnecttoControllerButtonPushed(app, event)
            DeviceDropDownValueChanged(app,event);
            
            app.StatusScreenTextArea.Value = "Remember to Choose the Correct Device from the List!";
            
            %% Get List of Serial Ports
            
            serialList = serialportlist;
            [serialIdx,tf] = listdlg('PromptString','Select a serial port','SelectionMode','single','ListString',serialList);
            
            if tf
            app.ScannerCOMPort = sscanf(serialList(serialIdx),"COM%d",1);
            else
                % No serial port selected
                app.StatusScreenTextArea.Value = "Invalid COM port selected or no COM port selected. Did not connect to controller";
                return;
            end
            Connect_AMC4030(app);
            
        end

        % Value changed function: DeviceDropDown
        function DeviceDropDownValueChanged(app, event)
            originalDir = cd;
            
            if app.DeviceDropDown.Value == "Josiah Laptop"
                app.RSTD_DLL_Path = 'D:\ti\mmwave_dfp_01_00_00_01\rf_eval\radarstudio\Clients\RtttNetClientController\RtttNetClientAPI.dll';
                app.SAR_GUI_Path = 'D:\Users\jws160130\Box\ISAR Rotation\SAR Scanner\SAR Scanner Codes\RSAR GUI';
                app.StatusScreenTextArea.Value = app.DeviceDropDown.Value;
            elseif app.DeviceDropDown.Value == "Lab Desktop"
                app.RSTD_DLL_Path = 'C:\ti\mmwave_dfp_01_00_00_01\rf_eval\radarstudio\Clients\RtttNetClientController\RtttNetClientAPI.dll';
                app.SAR_GUI_Path = 'C:\Users\jws160130\Box\ISAR Rotation\SAR Scanner\SAR Scanner Codes\RSAR GUI';
                app.StatusScreenTextArea.Value = app.DeviceDropDown.Value;
            end
            cd(app.SAR_GUI_Path)
            addpath("functions\")
            addpath("lib\")
            cd(originalDir)
        end

        % Button pushed function: SendSingleCommandButton
        function SendSingleCommandButtonPushed(app, event)
            Send_Single_Command(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            errorStatus = calllib('AMC4030','COM_API_OpenLink',app.ScannerCOMPort,115200);
            if (errorStatus == 1)
                if 1 == calllib('AMC4030','COM_API_Home',1,1,0)
                    app.TotalHorizontalMovementmmEditField.Value = 0;
                    app.TotalVerticalMovementmmEditField.Value = 0;
                end
            end
        end

        % Button pushed function: ConnectToRadarButton
        function ConnectToRadarButtonPushed(app, event)
            DeviceDropDownValueChanged(app,event);
            
            Connect_RadarStudio(app);
        end

        % Button pushed function: TriggerFrameButton
        function TriggerFrameButtonPushed(app, event)
            if (Trigger_Frame_RadarStudio() ~= 30000)
                app.RadarScreenTextArea.Value = 'Trigger Frame failed for device';
                return;
            else
                app.RadarScreenTextArea.Value = 'Trigger Frame is successful';
            end
        end

        % Button pushed function: EmergencyStopAcuatorButton
        function EmergencyStopAcuatorButtonPushed(app, event)
            if 1 == calllib('AMC4030','COM_API_StopAll')
                app.StatusScreenTextArea.Value = "EMERGENCY STOP";
            end
            app.sarScenarioActive = false;
        end

        % Button pushed function: LambdaSpacingButton
        function LambdaSpacingButtonPushed(app, event)
            c = 299792458; % m/s
            fc = 79e9; % Hz
            lambda = c/fc*1e3; % mm
            
            app.VerticalStepSize.Value = 2*lambda; % mm
        end

        % Button pushed function: StopCapturingRSARCommandButton
        function StopCapturingRSARCommandButtonPushed(app, event)
            if 1 == calllib('AMC4030','COM_API_StopAll')
                app.StatusScreenTextArea.Value = "Scan Manually Stopped";
            end
            app.sarScenarioActive = false;
        end

        % Button pushed function: StartCapturingRSARCommandButton
        function StartCapturingRSARCommandButtonPushed(app, event)
            RSAR_Capture(app);
        end

        % Button pushed function: LambdaSpacingButtonRect
        function LambdaSpacingButtonRectPushed(app, event)
            c = 299792458; % m/s
            fc = 79e9; % Hz
            lambda = c/fc*1e3; % mm
            
            app.VerticalStepSizeRect.Value = 2*lambda; % mm
        end

        % Button pushed function: StopCapturingRSARCommandButtonRect
        function StopCapturingRSARCommandButtonRectPushed(app, event)
            StopCapturingRSARCommandButtonPushed(app, event);
        end

        % Button pushed function: 
        % StartCapturingRSARCommandButtonRect
        function StartCapturingRSARCommandButtonRectPushed(app, event)
            RectSAR_Capture(app);
        end

        % Button pushed function: StartCapturingGestureCommandButton
        function StartCapturingGestureCommandButtonPushed(app, event)
            Gesture_Capture(app);
        end

        % Button pushed function: StartCapturingCommandButton_RH
        function StartCapturingCommandButton_RHPushed(app, event)
            RotationHorizontal_Capture(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.DeviceSetupPanel = uipanel(app.ConfigurationTab);
            app.DeviceSetupPanel.Title = '1. Device Setup';
            app.DeviceDropDownLabel = uilabel(app.DeviceSetupPanel);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.DeviceSetupPanel);
            app.DeviceDropDown.Items = {'Josiah Laptop', 'Lab Desktop'};
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceDropDownValueChanged, true);
            app.DeviceDropDown.Value = 'Lab Desktop';
            app.RadarTypeDropDownLabel = uilabel(app.DeviceSetupPanel);
            app.RadarTypeDropDownLabel.Text = 'Radar Type';
            app.RadarTypeDropDown = uidropdown(app.DeviceSetupPanel);
            app.RadarTypeDropDown.Items = {'TSW', 'DCA'};
            app.RadarTypeDropDown.Value = 'TSW';
            app.ControllerConnectionPanel = uipanel(app.ConfigurationTab);
            app.ControllerConnectionPanel.Title = '2. Controller Connection';
            app.ConnecttoControllerButton = uibutton(app.ControllerConnectionPanel, 'push');
            app.ConnecttoControllerButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoControllerButtonPushed, true);
            app.ConnecttoControllerButton.Text = 'Connect to Controller';
            app.VerticalSpeedmmsEditFieldLabel = uilabel(app.ControllerConnectionPanel);
            app.VerticalSpeedmmsEditFieldLabel.Text = 'Vertical Speed (mm/s)';
            app.VerticalSpeedmmsEditField = uieditfield(app.ControllerConnectionPanel, 'numeric');
            app.VerticalSpeedmmsEditField.Limits = [0 50];
            app.VerticalSpeedmmsEditField.Value = 50;
            app.HorizontalSpeedmmsEditFieldLabel = uilabel(app.ControllerConnectionPanel);
            app.HorizontalSpeedmmsEditFieldLabel.Text = 'Horizontal Speed (mm/s)';
            app.HorizontalSpeedmmsEditField = uieditfield(app.ControllerConnectionPanel, 'numeric');
            app.HorizontalSpeedmmsEditField.Limits = [0 100];
            app.HorizontalSpeedmmsEditField.Value = 50;
            app.ActuatorConnectionStatusLamp = uilamp(app.ControllerConnectionPanel);
            app.RotationSpeeddegsEditFieldLabel = uilabel(app.ControllerConnectionPanel);
            app.RotationSpeeddegsEditFieldLabel.Text = 'Rotation Speed (deg/s)';
            app.RotationSpeeddegsEditField = uieditfield(app.ControllerConnectionPanel, 'numeric');
            app.RotationSpeeddegsEditField.Limits = [0 60];
            app.RotationSpeeddegsEditField.Value = 36;
            app.mmWaveStudioConnectionPanel = uipanel(app.ConfigurationTab);
            app.mmWaveStudioConnectionPanel.Title = '3. mmWave Studio Connection';
            app.ConnectToRadarButton = uibutton(app.mmWaveStudioConnectionPanel, 'push');
            app.ConnectToRadarButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectToRadarButtonPushed, true);
            app.ConnectToRadarButton.Text = 'Connect To Radar';
            app.RadarConnectionStatusLamp = uilamp(app.mmWaveStudioConnectionPanel);
            app.TriggerFrameButton = uibutton(app.mmWaveStudioConnectionPanel, 'push');
            app.TriggerFrameButton.ButtonPushedFcn = createCallbackFcn(app, @TriggerFrameButtonPushed, true);
            app.TriggerFrameButton.Text = 'Trigger Frame';
            app.ScanNameEditFieldLabel = uilabel(app.mmWaveStudioConnectionPanel);
            app.ScanNameEditFieldLabel.Text = 'Scan Name';
            app.ScanNameEditField = uieditfield(app.mmWaveStudioConnectionPanel, 'text');
            app.ScanNameEditField.Value = 'scan0';
            app.ScanNameEditFieldLabel_2 = uilabel(app.mmWaveStudioConnectionPanel);
            app.ScanNameEditFieldLabel_2.Text = '.bin';
            app.SingleMovementPanel = uipanel(app.ConfigurationTab);
            app.SingleMovementPanel.Title = 'Single Movement';
            app.DesiredHorizontalMovementmmEditFieldLabel = uilabel(app.SingleMovementPanel);
            app.DesiredHorizontalMovementmmEditFieldLabel.Text = 'Desired Horizontal Movement (mm)';
            app.DesiredHorizontalMovementmmEditField = uieditfield(app.SingleMovementPanel, 'numeric');
            app.DesiredVerticalMovementmmEditFieldLabel = uilabel(app.SingleMovementPanel);
            app.DesiredVerticalMovementmmEditFieldLabel.Text = 'Desired Vertical Movement (mm)';
            app.DesiredVerticalMovementmmEditField = uieditfield(app.SingleMovementPanel, 'numeric');
            app.SendSingleCommandButton = uibutton(app.SingleMovementPanel, 'push');
            app.SendSingleCommandButton.ButtonPushedFcn = createCallbackFcn(app, @SendSingleCommandButtonPushed, true);
            app.SendSingleCommandButton.Text = 'Send Command';
            app.ResetButton = uibutton(app.SingleMovementPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DesiredRotationMovementdegEditFieldLabel = uilabel(app.SingleMovementPanel);
            app.DesiredRotationMovementdegEditFieldLabel.Text = 'Desired Rotation Movement (deg)';
            app.DesiredRotationMovementdegEditField = uieditfield(app.SingleMovementPanel, 'numeric');
            app.RotationSARTab = uitab(app.TabGroup);
            app.RotationSARTab.Title = 'Rotation-SAR';
            app.ContinuousRotationSARScenarioPanel = uipanel(app.RotationSARTab);
            app.ContinuousRotationSARScenarioPanel.Title = 'Continuous Rotation SAR Scenario';
            app.SetupPanel = uipanel(app.ContinuousRotationSARScenarioPanel);
            app.SetupPanel.Title = 'Setup';
            app.TimeBeforeCapturingsifTriggerFirstLabel = uilabel(app.SetupPanel);
            app.TimeBeforeCapturingsifTriggerFirstLabel.Text = 'Time Before Capturing (s) ((-) if Trigger First)';
            app.WaitTime = uieditfield(app.SetupPanel, 'numeric');
            app.WaitTime.Value = -1;
            app.RotationSizedegLabel = uilabel(app.SetupPanel);
            app.RotationSizedegLabel.Text = 'Rotation Size (deg)';
            app.RotationSize = uieditfield(app.SetupPanel, 'numeric');
            app.RotationSize.Value = 360;
            app.VerticalStepSizemmLabel = uilabel(app.SetupPanel);
            app.VerticalStepSizemmLabel.Text = 'Vertical Step Size (mm)';
            app.VerticalStepSize = uieditfield(app.SetupPanel, 'numeric');
            app.VerticalStepSize.ValueDisplayFormat = '%11.5f';
            app.VerticalStepSize.Value = 7.58968248101265;
            app.NumberofVerticalStepsLabel = uilabel(app.SetupPanel);
            app.NumberofVerticalStepsLabel.Text = 'Number of Vertical Steps';
            app.nVerticalSteps = uieditfield(app.SetupPanel, 'numeric');
            app.nVerticalSteps.Value = 64;
            app.LambdaSpacingButton = uibutton(app.SetupPanel, 'push');
            app.LambdaSpacingButton.ButtonPushedFcn = createCallbackFcn(app, @LambdaSpacingButtonPushed, true);
            app.LambdaSpacingButton.Text = '2 Lambda Spacing';
            app.StartCapturingRSARCommandButton = uibutton(app.ContinuousRotationSARScenarioPanel, 'push');
            app.StartCapturingRSARCommandButton.ButtonPushedFcn = createCallbackFcn(app, @StartCapturingRSARCommandButtonPushed, true);
            app.StartCapturingRSARCommandButton.Text = 'Start Capturing';
            app.StopCapturingRSARCommandButton = uibutton(app.ContinuousRotationSARScenarioPanel, 'push');
            app.StopCapturingRSARCommandButton.ButtonPushedFcn = createCallbackFcn(app, @StopCapturingRSARCommandButtonPushed, true);
            app.StopCapturingRSARCommandButton.Text = 'Stop Capturing';
            app.RectilinearSARTab = uitab(app.TabGroup);
            app.RectilinearSARTab.Title = 'Rectilinear-SAR';
            app.ContinuousRectilinearSARScenarioPanel = uipanel(app.RectilinearSARTab);
            app.ContinuousRectilinearSARScenarioPanel.Title = 'Continuous Rectilinear SAR Scenario';
            app.SetupPanelRect = uipanel(app.ContinuousRectilinearSARScenarioPanel);
            app.SetupPanelRect.Title = 'Setup';
            app.TimeBeforeCapturingsifTriggerFirstLabel_2 = uilabel(app.SetupPanelRect);
            app.TimeBeforeCapturingsifTriggerFirstLabel_2.Text = 'Time Before Capturing (s) ((-) if Trigger First)';
            app.WaitTimeRect = uieditfield(app.SetupPanelRect, 'numeric');
            app.WaitTimeRect.Value = -0.64;
            app.HorizontalScanSizemmLabel = uilabel(app.SetupPanelRect);
            app.HorizontalScanSizemmLabel.Text = 'Horizontal Scan Size (mm)';
            app.HorizontalScanSize_mm = uieditfield(app.SetupPanelRect, 'numeric');
            app.HorizontalScanSize_mm.Value = 400;
            app.VerticalStepSizemmLabel_2 = uilabel(app.SetupPanelRect);
            app.VerticalStepSizemmLabel_2.Text = 'Vertical Step Size (mm)';
            app.VerticalStepSizeRect = uieditfield(app.SetupPanelRect, 'numeric');
            app.VerticalStepSizeRect.ValueDisplayFormat = '%11.5f';
            app.VerticalStepSizeRect.Value = 10;
            app.NumberofVerticalStepsLabel_2 = uilabel(app.SetupPanelRect);
            app.NumberofVerticalStepsLabel_2.Text = 'Number of Vertical Steps';
            app.nVerticalStepsRect = uieditfield(app.SetupPanelRect, 'numeric');
            app.nVerticalStepsRect.Value = 10;
            app.LambdaSpacingButtonRect = uibutton(app.SetupPanelRect, 'push');
            app.LambdaSpacingButtonRect.ButtonPushedFcn = createCallbackFcn(app, @LambdaSpacingButtonRectPushed, true);
            app.LambdaSpacingButtonRect.Text = '2 Lambda Spacing';
            app.StartCapturingRSARCommandButtonRect = uibutton(app.ContinuousRectilinearSARScenarioPanel, 'push');
            app.StartCapturingRSARCommandButtonRect.ButtonPushedFcn = createCallbackFcn(app, @StartCapturingRSARCommandButtonRectPushed, true);
            app.StartCapturingRSARCommandButtonRect.Text = 'Start Capturing';
            app.StopCapturingRSARCommandButtonRect = uibutton(app.ContinuousRectilinearSARScenarioPanel, 'push');
            app.StopCapturingRSARCommandButtonRect.ButtonPushedFcn = createCallbackFcn(app, @StopCapturingRSARCommandButtonRectPushed, true);
            app.StopCapturingRSARCommandButtonRect.Text = 'Stop Capturing';
            app.TwoDirectionScanningRectCheckBox = uicheckbox(app.ContinuousRectilinearSARScenarioPanel);
            app.TwoDirectionScanningRectCheckBox.Text = 'Two Direction Scanning';
            app.TwoDirectionScanningRectCheckBox.Value = true;
            app.WaitBeforeStartRectCheckBox = uicheckbox(app.ContinuousRectilinearSARScenarioPanel);
            app.WaitBeforeStartRectCheckBox.Text = 'Wait Before Start';
            app.WaitBeforeStartRectCheckBox.Value = true;
            app.RotationHorizontalTab = uitab(app.TabGroup);
            app.RotationHorizontalTab.Title = 'Rotation-Horizontal';
            app.RotationHorizontalScanningScenarioPanel = uipanel(app.RotationHorizontalTab);
            app.RotationHorizontalScanningScenarioPanel.Title = 'Rotation + Horizontal Scanning Scenario';
            app.SetupPanel_RH = uipanel(app.RotationHorizontalScanningScenarioPanel);
            app.SetupPanel_RH.Title = 'Setup';
            app.TimeBeforeCapturingsifTriggerFirstLabel_3 = uilabel(app.SetupPanel_RH);
            app.TimeBeforeCapturingsifTriggerFirstLabel_3.Text = 'Time Before Capturing (s) ((-) if Trigger First)';
            app.WaitTime_RH = uieditfield(app.SetupPanel_RH, 'numeric');
            app.WaitTime_RH.Value = -0.5;
            app.HorizontalScanSizemmLabel_2 = uilabel(app.SetupPanel_RH);
            app.HorizontalScanSizemmLabel_2.Text = 'Horizontal Scan Size (mm)';
            app.HorizontalScanSize_mm_RH = uieditfield(app.SetupPanel_RH, 'numeric');
            app.HorizontalScanSize_mm_RH.Value = 450;
            app.RotationStepSizedegLabel = uilabel(app.SetupPanel_RH);
            app.RotationStepSizedegLabel.Text = 'Rotation Step Size (deg)';
            app.RotationStepSize_deg_RH = uieditfield(app.SetupPanel_RH, 'numeric');
            app.RotationStepSize_deg_RH.Value = 3.6;
            app.NumberofRotationStepsLabel = uilabel(app.SetupPanel_RH);
            app.NumberofRotationStepsLabel.Text = 'Number of Rotation Steps';
            app.nRotationSteps_RH = uieditfield(app.SetupPanel_RH, 'numeric');
            app.nRotationSteps_RH.Value = 100;
            app.StartCapturingCommandButton_RH = uibutton(app.RotationHorizontalScanningScenarioPanel, 'push');
            app.StartCapturingCommandButton_RH.ButtonPushedFcn = createCallbackFcn(app, @StartCapturingCommandButton_RHPushed, true);
            app.StartCapturingCommandButton_RH.Text = 'Start Capturing';
            app.StopCapturingCommandButton_RH = uibutton(app.RotationHorizontalScanningScenarioPanel, 'push');
            app.StopCapturingCommandButton_RH.Text = 'Stop Capturing';
            app.GesturesTab = uitab(app.TabGroup);
            app.GesturesTab.Title = 'Gestures';
            app.GestureScenarioPanel = uipanel(app.GesturesTab);
            app.GestureScenarioPanel.Title = 'Gesture Scenario';
            app.SetupPanelGesture = uipanel(app.GestureScenarioPanel);
            app.SetupPanelGesture.Title = 'Setup';
            app.CaptureTimesLabel = uilabel(app.SetupPanelGesture);
            app.CaptureTimesLabel.Text = 'Capture Time (s)';
            app.CaptureTime_s = uieditfield(app.SetupPanelGesture, 'numeric');
            app.CaptureTime_s.Value = 2.56;
            app.TimeBetweenCapturessLabel = uilabel(app.SetupPanelGesture);
            app.TimeBetweenCapturessLabel.Text = 'Time Between Captures (s)';
            app.TimeBetweenCaptures_s = uieditfield(app.SetupPanelGesture, 'numeric');
            app.TimeBetweenCaptures_s.Value = 0.44;
            app.BufferTimesLabel = uilabel(app.SetupPanelGesture);
            app.BufferTimesLabel.Text = 'Buffer Time (s)';
            app.BufferTime_s = uieditfield(app.SetupPanelGesture, 'numeric');
            app.BufferTime_s.Value = 0.2;
            app.NumberofCapturesLabel = uilabel(app.SetupPanelGesture);
            app.NumberofCapturesLabel.Text = 'Number of Captures';
            app.nGestureCaptures = uieditfield(app.SetupPanelGesture, 'numeric');
            app.nGestureCaptures.Value = 5;
            app.StartCapturingGestureCommandButton = uibutton(app.GestureScenarioPanel, 'push');
            app.StartCapturingGestureCommandButton.ButtonPushedFcn = createCallbackFcn(app, @StartCapturingGestureCommandButtonPushed, true);
            app.StartCapturingGestureCommandButton.Text = 'Start Capturing';
            app.StopCapturingGestureCommandButtonRect = uibutton(app.GestureScenarioPanel, 'push');
            app.StopCapturingGestureCommandButtonRect.Text = 'Stop Capturing';
            app.WaitBeforeStartCheckBox = uicheckbox(app.GestureScenarioPanel);
            app.WaitBeforeStartCheckBox.Text = 'Wait Before Start';
            app.WaitBeforeStartCheckBox.Value = true;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Created by Muhammet E. Yanik'; 'Extended by Josiah W. Smith'; 'Advisor: Prof. Murat Torlak'; 'The University of Texas at Dallas'};
            app.EmergencyStopAcuatorButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopAcuatorButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStopAcuatorButtonPushed, true);
            app.EmergencyStopAcuatorButton.Text = 'Emergency Stop Acuator';
            app.TotalHorizontalMovementmmEditFieldLabel = uilabel(app.UIFigure);
            app.TotalHorizontalMovementmmEditFieldLabel.Text = 'Total Horizontal Movement (mm)';
            app.TotalHorizontalMovementmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalHorizontalMovementmmEditField.ValueDisplayFormat = '%11.5f';
            app.TotalHorizontalMovementmmEditField.Editable = 'off';
            app.TotalVerticalMovementmmEditFieldLabel = uilabel(app.UIFigure);
            app.TotalVerticalMovementmmEditFieldLabel.Text = 'Total Vertical Movement (mm)';
            app.TotalVerticalMovementmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalVerticalMovementmmEditField.ValueDisplayFormat = '%11.5f';
            app.TotalVerticalMovementmmEditField.Editable = 'off';
            app.TotalRotationMovementdegEditFieldLabel = uilabel(app.UIFigure);
            app.TotalRotationMovementdegEditFieldLabel.Text = 'Total Rotation Movement (deg)';
            app.TotalRotationMovementdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalRotationMovementdegEditField.ValueDisplayFormat = '%11.5f';
            app.TotalRotationMovementdegEditField.Editable = 'off';
            app.RadarScreenTextAreaLabel = uilabel(app.UIFigure);
            app.RadarScreenTextAreaLabel.Text = 'Radar Screen';
            app.RadarScreenTextArea = uitextarea(app.UIFigure);
            app.StatusScreenTextAreaLabel = uilabel(app.UIFigure);
            app.StatusScreenTextAreaLabel.Text = 'Status Screen';
            app.StatusScreenTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RSAR_GUI_Josiah

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end