classdef SAR_GUI_Josiah < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label                           matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ConfigurationTab                matlab.ui.container.Tab
        VerticalSpeedmmsEditFieldLabel  matlab.ui.control.Label
        VerticalSpeedmmsEditField       matlab.ui.control.NumericEditField
        HorizontalSpeedmmsEditFieldLabel  matlab.ui.control.Label
        HorizontalSpeedmmsEditField     matlab.ui.control.NumericEditField
        DisconnectfromActuatorButton    matlab.ui.control.Button
        ConnecttoControllerButton       matlab.ui.control.Button
        SingleMovementPanel             matlab.ui.container.Panel
        DesiredHorizontalMovementmmEditFieldLabel  matlab.ui.control.Label
        DesiredHorizontalMovementmmEditField  matlab.ui.control.NumericEditField
        DesiredVerticalMovementmmEditFieldLabel  matlab.ui.control.Label
        DesiredVerticalMovementmmEditField  matlab.ui.control.NumericEditField
        SendSingleCommandButton         matlab.ui.control.Button
        TotalMovementLabel              matlab.ui.control.Label
        TotalMovementTextArea           matlab.ui.control.TextArea
        ResetButton                     matlab.ui.control.Button
        ConnectionStatusLampLabel       matlab.ui.control.Label
        ActuatorConnectionStatusLamp    matlab.ui.control.Lamp
        ConnectToRadarButton            matlab.ui.control.Button
        ConnectionStatusLampLabel_3     matlab.ui.control.Label
        RadarConnectionStatusLamp       matlab.ui.control.Lamp
        TriggerFrameButton              matlab.ui.control.Button
        RadarScreenLabel                matlab.ui.control.Label
        RadarScreenTextArea             matlab.ui.control.TextArea
        DeviceDropDownLabel             matlab.ui.control.Label
        DeviceDropDown                  matlab.ui.control.DropDown
        RadarTypeDropDownLabel          matlab.ui.control.Label
        RadarTypeDropDown               matlab.ui.control.DropDown
        ConnectToHSDCButton             matlab.ui.control.Button
        ConnectionStatusLampLabel_4     matlab.ui.control.Label
        HSDCConnectionStatusLamp        matlab.ui.control.Lamp
        HSDCScreenTextAreaLabel         matlab.ui.control.Label
        HSDCScreenTextArea              matlab.ui.control.TextArea
        SARScenarioDiscreteTab          matlab.ui.container.Tab
        SARDataCapturingDiscreteCapturingPanel  matlab.ui.container.Panel
        StartCapturingSARCommandButton  matlab.ui.control.Button
        HorizontalMovementforEachStepmmEditFieldLabel  matlab.ui.control.Label
        HorizontalMovementforEachStepmmEditField  matlab.ui.control.NumericEditField
        WaitTimeBetweenStepssEditField_2Label  matlab.ui.control.Label
        WaitTimeBetweenStepsSAREditField  matlab.ui.control.NumericEditField
        RadarTypeLabel                  matlab.ui.control.Label
        RadarTypeSwitchSAR              matlab.ui.control.Switch
        ResetMovementSARCommandButton   matlab.ui.control.Button
        TotalMovementTextArea_2Label    matlab.ui.control.Label
        TotalMovementSARTextArea        matlab.ui.control.TextArea
        StatusScreenLabel_2             matlab.ui.control.Label
        StatusScreenSARTextArea         matlab.ui.control.TextArea
        StopCapturingSARCommandButton   matlab.ui.control.Button
        NumberofStepsatHorizontalAxisSpinnerLabel  matlab.ui.control.Label
        NumberofStepsatHorizontalAxisSpinner  matlab.ui.control.Spinner
        NumberofStepsatVerticalAxisSpinnerLabel  matlab.ui.control.Label
        NumberofStepsatVerticalAxisSpinner  matlab.ui.control.Spinner
        VerticalMovementScenariommEditFieldLabel  matlab.ui.control.Label
        VerticalMovementScenariommEditField  matlab.ui.control.EditField
        Blankbetweenvalues0919Label     matlab.ui.control.Label
        ChecklistLabel_3                matlab.ui.control.Label
        ConnecttoActuatorLabel_3        matlab.ui.control.Label
        ConnecttoRadarLabel_3           matlab.ui.control.Label
        ConfigureRadarStudioandHSDCLabel_3  matlab.ui.control.Label
        SetRPMLabel_2                   matlab.ui.control.Label
        SARScenarioContinuousTab        matlab.ui.container.Tab
        SARDataCapturingContinuousCapturingPanel  matlab.ui.container.Panel
        StartCapturingContSARCommandButton  matlab.ui.control.Button
        HorizontalScanSizemmLabel       matlab.ui.control.Label
        HorizontalScanSizemmContSAREditField  matlab.ui.control.NumericEditField
        TimeBeforeCapturingsifTriggerFirstLabel  matlab.ui.control.Label
        WaitTimeBeforeCapturingContSAREditField  matlab.ui.control.NumericEditField
        ResetMovementContSARCommandButton  matlab.ui.control.Button
        TotalMovementTextArea_2Label_2  matlab.ui.control.Label
        TotalMovementContSARTextArea    matlab.ui.control.TextArea
        StatusScreenLabel               matlab.ui.control.Label
        StatusScreenContSARTextArea     matlab.ui.control.TextArea
        StopCapturingContSARCommandButton  matlab.ui.control.Button
        NumberofStepsatVerticalScanLabel  matlab.ui.control.Label
        VerticalScanStepsContSARSpinner  matlab.ui.control.Spinner
        Blankbetweenvalues0919ContSARLabel  matlab.ui.control.Label
        FramePeriodicitymsLabel         matlab.ui.control.Label
        FramePeriodicitymsContSAREditField  matlab.ui.control.NumericEditField
        NumberofFramesEditFieldLabel    matlab.ui.control.Label
        NumberofFramesContSAREditField  matlab.ui.control.NumericEditField
        RecommendHorizontalScanSizeButtonContSAR  matlab.ui.control.Button
        RecommendNumberOfFramesButtonContSAR  matlab.ui.control.Button
        ChecklistLabel_5                matlab.ui.control.Label
        ConnecttoActuatorLabel_5        matlab.ui.control.Label
        ConnecttoRadarLabel_5           matlab.ui.control.Label
        ConfigureRadarStudioandHSDCLabel_5  matlab.ui.control.Label
        SetSpeedLabel                   matlab.ui.control.Label
        LambdaSpacingButton             matlab.ui.control.Button
        VerticalStepSizemmEditFieldLabel  matlab.ui.control.Label
        VerticalStepSizemmEditField     matlab.ui.control.NumericEditField
        BackandForthScanningCheckBox    matlab.ui.control.CheckBox
        LargeScannerCheckBox            matlab.ui.control.CheckBox
        RotatorTab                      matlab.ui.container.Tab
        DegtoRotateEditFieldLabel       matlab.ui.control.Label
        DegtoRotateEditField            matlab.ui.control.NumericEditField
        RotatorTextAreaLabel            matlab.ui.control.Label
        RotatorTextArea                 matlab.ui.control.TextArea
        RotateButton                    matlab.ui.control.Button
        RotationSpeeddegsEditFieldLabel  matlab.ui.control.Label
        RotationSpeeddegsEditField      matlab.ui.control.NumericEditField
        RotationSARTab                  matlab.ui.container.Tab
        StartCapturingRotSARCommandButton  matlab.ui.control.Button
        StopCapturingRotSARCommandButton  matlab.ui.control.Button
        StartingHorizontalPositionEditFieldLabel  matlab.ui.control.Label
        StartingHorizontalPositionEditField  matlab.ui.control.NumericEditField
        StartingVerticalPositionEditFieldLabel  matlab.ui.control.Label
        StartingVerticalPositionEditField  matlab.ui.control.NumericEditField
        RotationStepSizedegEditFieldLabel  matlab.ui.control.Label
        RotationStepSizedegEditField    matlab.ui.control.NumericEditField
        NumberofRotationStepsEditFieldLabel  matlab.ui.control.Label
        NumberofRotationStepsEditField  matlab.ui.control.NumericEditField
        RotationSARTextAreaLabel        matlab.ui.control.Label
        RotationSARTextArea             matlab.ui.control.TextArea
        EmergencyStopAcuatorButton      matlab.ui.control.Button
        TotalHorizontalMovementmmEditFieldLabel  matlab.ui.control.Label
        TotalHorizontalMovementmmEditField  matlab.ui.control.NumericEditField
        TotalVerticalMovementmmEditFieldLabel  matlab.ui.control.Label
        TotalVerticalMovementmmEditField  matlab.ui.control.NumericEditField
        TotalRotationMovementdegEditFieldLabel  matlab.ui.control.Label
        TotalRotationMovementdegEditField  matlab.ui.control.NumericEditField
        ScanNameEditFieldLabel          matlab.ui.control.Label
        ScanNameEditField               matlab.ui.control.EditField
        ScanNameEditFieldLabel_2        matlab.ui.control.Label
    end

    
    properties (Access = public)
        ScannerCOMPort; % AMC4030 Controller Serial Port
        
        isActuatorConnected = false;
        serialPortRadar;
        
        totalRotationMovement = 0;
        
        scanName = "noScanName";
        
        textMovementString = ' ';
        textRadarStudioErrorString = ' ';
        isTIRadarConnected = false;
        sarScenarioActive = false;
        isINRASRadarConnected = false;
        
        SAR_GUI_Path;
        RSTD_DLL_Path;
        HSDC_DLL_Path;
        isHSDCloaded = false;
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectToRadarButton
        function ConnectToRadarButtonPushed(app, event)
            
            DeviceDropDownValueChanged(app,event);
            
            Connect_RadarStudio(app);
            
        end

        % Button pushed function: ConnecttoControllerButton
        function ConnecttoControllerButtonPushed(app, event)
            
            % Make sure device drop down list has been called and proper
            % values have been initialized
            
            DeviceDropDownValueChanged(app,event);
            
            app.RadarScreenTextArea.Value = "Remember to Choose the Correct Device from the List!";
            
            Connect_AMC4030(app);
            %Connect_ESP32(app);
        end

        % Button pushed function: DisconnectfromActuatorButton
        function DisconnectfromActuatorButtonPushed(app, event)
            %Disconnect_ESP32(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            ConnecttoControllerButtonPushed(app, event);
            if 1 == calllib('AMC4030','COM_API_Home',1,1,0)
                app.TotalHorizontalMovementmmEditField.Value = 0;
                app.TotalVerticalMovementmmEditField.Value = 0;
            end
            app.textMovementString = ['Horizontal:       ', num2str(app.TotalHorizontalMovementmmEditField.Value), ' mm', newline, newline, ...;
                'Vertical:        ', num2str(app.TotalVerticalMovementmmEditField.Value), ' mm'];
            app.TotalMovementTextArea.Value = app.textMovementString;
        end

        % Button pushed function: ResetMovementSARCommandButton
        function ResetMovementSARCommandButtonPushed(app, event)
            ResetButtonPushed(app, event);
        end

        % Callback function
        function SendContinuousCommandButtonPushed(app, event)
            Send_Continuous_Command(app);
        end

        % Button pushed function: SendSingleCommandButton
        function SendSingleCommandButtonPushed(app, event)
            Send_Single_Command(app);
        end

        % Button pushed function: StartCapturingSARCommandButton
        function StartCapturingSARCommandButtonPushed(app, event)
            %Discrete_SAR_Capture(app); % Only Optimized for ESP32 right now
        end

        % Button pushed function: StopCapturingSARCommandButton
        function StopCapturingSARCommandButtonPushed(app, event)
            app.sarScenarioActive = false;
        end

        % Button pushed function: TriggerFrameButton
        function TriggerFrameButtonPushed(app, event)
            if (Trigger_Frame_RadarStudio() ~=30000)
                app.RadarScreenTextArea.Value = 'Trigger Frame failed for device';
                return;
            else
                app.RadarScreenTextArea.Value = 'Trigger Frame is successful';
            end
        end

        % Callback function
        function SetRPMButtonPushed(app, event)
            revolutionPerMinute = app.HorizontalSpeedmmsEditField.Value;
            fprintf(app.serialPortActuator,['S' num2str(revolutionPerMinute)]);
            disp(['S' num2str(revolutionPerMinute)])
        end

        % Callback function
        function EmergencyStopofActuatorButtonPushed(app, event)
            fprintf(app.serialPortActuator,'E');
        end

        % Callback function
        function EmergencyStopofActuatorButtonSARPushed(app, event)
            fprintf(app.serialPortActuator,'E');
        end

        % Button pushed function: StartCapturingContSARCommandButton
        function StartCapturingContSARCommandButtonPushed(app, event)
            Continuous_SAR_Capture(app);
        end

        % Button pushed function: StopCapturingContSARCommandButton
        function StopCapturingContSARCommandButtonPushed(app, event)
            app.sarScenarioActive = false;
        end

        % Button pushed function: 
        % RecommendHorizontalScanSizeButtonContSAR
        function RecommendHorizontalScanSizeButtonContSARPushed(app, event)
            framePeriodicity_spf = app.FramePeriodicitymsContSAREditField.Value/1000;
            nFrames = app.NumberofFramesContSAREditField.Value;
            
            waitDuration = app.WaitTimeBeforeCapturingContSAREditField.Value;
            totalScanTime = framePeriodicity_spf*nFrames + 2*waitDuration;
            
            app.HorizontalScanSizemmContSAREditField.Value = totalScanTime*horizontalSpeedmmps;
        end

        % Button pushed function: EmergencyStopAcuatorButton
        function EmergencyStopAcuatorButtonPushed(app, event)
            %             fprintf(app.serialPortActuator,['E']);
            if 1 == calllib('AMC4030','COM_API_StopAll')
                disp('EMERGENCY STOP')
            end
        end

        % Button pushed function: 
        % RecommendNumberOfFramesButtonContSAR
        function RecommendNumberOfFramesButtonContSARPushed(app, event)
            horizontalScanSize = app.HorizontalScanSizemmContSAREditField.Value;
            horizontalSpeedmmps = app.HorizontalSpeedmmsEditField.Value;
            framePeriodicity_spf = app.FramePeriodicitymsContSAREditField.Value/1000;
            waitDuration = app.WaitTimeBeforeCapturingContSAREditField.Value;
            totalScanTime = horizontalScanSize/horizontalSpeedmmps;
            app.NumberofFramesContSAREditField.Value = (totalScanTime - 2*waitDuration) / (framePeriodicity_spf);
        end

        % Value changed function: HorizontalSpeedmmsEditField
        function HorizontalSpeedmmsEditFieldValueChanged(app, event)
            value = app.HorizontalSpeedmmsEditField.Value;
        end

        % Value changed function: DeviceDropDown
        function DeviceDropDownValueChanged(app, event)
            originalDir = cd;
            
            if app.DeviceDropDown.Value == "Josiah Laptop"
                app.RSTD_DLL_Path = 'D:\ti\mmwave_dfp_01_00_00_01\rf_eval\radarstudio\Clients\RtttNetClientController\RtttNetClientAPI.dll';
                app.SAR_GUI_Path = 'D:\Users\jws160130\Box\ISAR Rotation\SAR Scanner\SAR Scanner Codes\SAR GUI'; % change
                app.ScannerCOMPort = 9;
                app.RadarScreenTextArea.Value = app.DeviceDropDown.Value;
            elseif app.DeviceDropDown.Value == "Lab Desktop"
                app.RSTD_DLL_Path = 'C:\ti\mmwave_dfp_01_00_00_01\rf_eval\radarstudio\Clients\RtttNetClientController\RtttNetClientAPI.dll';
                app.HSDC_DLL_Path = 'C:\Program Files (x86)\Texas Instruments\High Speed Data Converter Pro\HSDCPro Automation DLL\64Bit DLL\HSDCProAutomation_64Bit.dll';
                app.SAR_GUI_Path = 'C:\Users\jws160130\Box\ISAR Rotation\SAR Scanner\SAR Scanner Codes\SAR GUI';
                app.ScannerCOMPort = 4;
                app.RadarScreenTextArea.Value = app.DeviceDropDown.Value;
            end
            cd(app.SAR_GUI_Path)
            addpath("functions\")
            addpath("lib\")
            cd(originalDir)
            %app.RSTD_DLL_Path = './lib/RtttNetClientAPI.dll';
            %app.HSDC_DLL_Path = './lib/HSDCProAutomation_64Bit.dll';
        end

        % Button pushed function: LambdaSpacingButton
        function LambdaSpacingButtonPushed(app, event)
            c = 299792458; % m/s
            fc = 79e9; % Hz
            lambda = c/fc*1e3; % mm
            
            app.VerticalStepSizemmEditField.Value = 2*lambda; % mm
        end

        % Callback function
        function VerticalStepSizemmContSAREditFieldValueChanged(app, event)
            disp("Vertical Step Size: " + app.VerticalStepSizemmContSAREditField.Value)
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            if COM_API_Jog(app.ScannerCOMPort,2,app.DegtoRotateEditField.Value,app.RotationSpeeddegsEditField.Value)
                app.TotalRotationMovementdegEditField.Value = app.TotalRotationMovementdegEditField.Value + app.DegtoRotateEditField.Value;
                app.RotatorTextArea.Value = "Rotated " + app.DegtoRotateEditField.Value + " degrees";
            end
        end

        % Button pushed function: StartCapturingRotSARCommandButton
        function StartCapturingRotSARCommandButtonPushed(app, event)
            Rotation_Continuous_SAR_Capture(app);
        end

        % Button pushed function: ConnectToHSDCButton
        function ConnectToHSDCButtonPushed(app, event)
            Connect_HDSC(app);
        end

        % Value changed function: ScanNameEditField
        function ScanNameEditFieldValueChanged(app, event)
            app.scanName = app.ScanNameEditField.Value;
        end

        % Button pushed function: ResetMovementContSARCommandButton
        function ResetMovementContSARCommandButtonPushed(app, event)
            answer = "YES";
            if app.sarScenarioActive
                answer = convertCharsToStrings(questdlg('Are you sure you want to home the X and Y axes during the Scan???', ...
                   	'WARNING', ...
                   	"YES","NO","NO"));
            end
            if answer == "YES"
                if 1 == calllib('AMC4030','COM_API_StopAll')
                    disp('EMERGENCY STOP')
                end
                ConnecttoControllerButtonPushed(app, event);
                if 1 == calllib('AMC4030','COM_API_Home',1,1,0)
                    app.TotalHorizontalMovementmmEditField.Value = 0;
                    app.TotalVerticalMovementmmEditField.Value = 0;
                end
                app.textMovementString = ['Horizontal:       ', num2str(app.TotalHorizontalMovementmmEditField.Value), ' mm', newline, newline, ...;
                    'Vertical:        ', num2str(app.TotalVerticalMovementmmEditField.Value), ' mm'];
                app.TotalMovementTextArea.Value = app.textMovementString;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Created by Muhammet E. Yanik'; 'Extended by Josiah W. Smith'; 'Advisor: Prof. Murat Torlak'; 'The University of Texas at Dallas'};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.VerticalSpeedmmsEditFieldLabel = uilabel(app.ConfigurationTab);
            app.VerticalSpeedmmsEditFieldLabel.Text = 'Vertical Speed (mm/s)';
            app.VerticalSpeedmmsEditField = uieditfield(app.ConfigurationTab, 'numeric');
            app.VerticalSpeedmmsEditField.Value = 50;
            app.HorizontalSpeedmmsEditFieldLabel = uilabel(app.ConfigurationTab);
            app.HorizontalSpeedmmsEditFieldLabel.Text = 'Horizontal Speed (mm/s)';
            app.HorizontalSpeedmmsEditField = uieditfield(app.ConfigurationTab, 'numeric');
            app.HorizontalSpeedmmsEditField.Limits = [0 100];
            app.HorizontalSpeedmmsEditField.ValueChangedFcn = createCallbackFcn(app, @HorizontalSpeedmmsEditFieldValueChanged, true);
            app.HorizontalSpeedmmsEditField.Value = 100;
            app.DisconnectfromActuatorButton = uibutton(app.ConfigurationTab, 'push');
            app.DisconnectfromActuatorButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectfromActuatorButtonPushed, true);
            app.DisconnectfromActuatorButton.Text = 'Disconnect from Actuator';
            app.ConnecttoControllerButton = uibutton(app.ConfigurationTab, 'push');
            app.ConnecttoControllerButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoControllerButtonPushed, true);
            app.ConnecttoControllerButton.Text = 'Connect to Controller';
            app.SingleMovementPanel = uipanel(app.ConfigurationTab);
            app.SingleMovementPanel.Title = 'Single Movement';
            app.DesiredHorizontalMovementmmEditFieldLabel = uilabel(app.SingleMovementPanel);
            app.DesiredHorizontalMovementmmEditFieldLabel.Text = 'Desired Horizontal Movement (mm)';
            app.DesiredHorizontalMovementmmEditField = uieditfield(app.SingleMovementPanel, 'numeric');
            app.DesiredVerticalMovementmmEditFieldLabel = uilabel(app.SingleMovementPanel);
            app.DesiredVerticalMovementmmEditFieldLabel.Text = 'Desired Vertical Movement (mm)';
            app.DesiredVerticalMovementmmEditField = uieditfield(app.SingleMovementPanel, 'numeric');
            app.SendSingleCommandButton = uibutton(app.SingleMovementPanel, 'push');
            app.SendSingleCommandButton.ButtonPushedFcn = createCallbackFcn(app, @SendSingleCommandButtonPushed, true);
            app.SendSingleCommandButton.Text = 'Send Command';
            app.TotalMovementLabel = uilabel(app.SingleMovementPanel);
            app.TotalMovementLabel.Text = 'Total Movement';
            app.TotalMovementTextArea = uitextarea(app.SingleMovementPanel);
            app.ResetButton = uibutton(app.SingleMovementPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ConnectionStatusLampLabel = uilabel(app.ConfigurationTab);
            app.ConnectionStatusLampLabel.Text = 'Connection Status';
            app.ActuatorConnectionStatusLamp = uilamp(app.ConfigurationTab);
            app.ConnectToRadarButton = uibutton(app.ConfigurationTab, 'push');
            app.ConnectToRadarButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectToRadarButtonPushed, true);
            app.ConnectToRadarButton.Text = 'Connect To Radar';
            app.ConnectionStatusLampLabel_3 = uilabel(app.ConfigurationTab);
            app.ConnectionStatusLampLabel_3.Text = 'Connection Status';
            app.RadarConnectionStatusLamp = uilamp(app.ConfigurationTab);
            app.TriggerFrameButton = uibutton(app.ConfigurationTab, 'push');
            app.TriggerFrameButton.ButtonPushedFcn = createCallbackFcn(app, @TriggerFrameButtonPushed, true);
            app.TriggerFrameButton.Text = 'Trigger Frame';
            app.RadarScreenLabel = uilabel(app.ConfigurationTab);
            app.RadarScreenLabel.Text = 'Radar Screen';
            app.RadarScreenTextArea = uitextarea(app.ConfigurationTab);
            app.DeviceDropDownLabel = uilabel(app.ConfigurationTab);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.ConfigurationTab);
            app.DeviceDropDown.Items = {'Josiah Laptop', 'Lab Desktop'};
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceDropDownValueChanged, true);
            app.DeviceDropDown.Value = 'Lab Desktop';
            app.RadarTypeDropDownLabel = uilabel(app.ConfigurationTab);
            app.RadarTypeDropDownLabel.Text = 'Radar Type';
            app.RadarTypeDropDown = uidropdown(app.ConfigurationTab);
            app.RadarTypeDropDown.Items = {'TSW', 'DCA'};
            app.RadarTypeDropDown.Value = 'TSW';
            app.ConnectToHSDCButton = uibutton(app.ConfigurationTab, 'push');
            app.ConnectToHSDCButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectToHSDCButtonPushed, true);
            app.ConnectToHSDCButton.Text = 'Connect To HSDC';
            app.ConnectionStatusLampLabel_4 = uilabel(app.ConfigurationTab);
            app.ConnectionStatusLampLabel_4.Text = 'Connection Status';
            app.HSDCConnectionStatusLamp = uilamp(app.ConfigurationTab);
            app.HSDCScreenTextAreaLabel = uilabel(app.ConfigurationTab);
            app.HSDCScreenTextAreaLabel.Text = 'HSDC Screen';
            app.HSDCScreenTextArea = uitextarea(app.ConfigurationTab);
            app.SARScenarioDiscreteTab = uitab(app.TabGroup);
            app.SARScenarioDiscreteTab.Title = 'SAR Scenario (Discrete)';
            app.SARDataCapturingDiscreteCapturingPanel = uipanel(app.SARScenarioDiscreteTab);
            app.SARDataCapturingDiscreteCapturingPanel.Title = 'SAR Data Capturing (Discrete Capturing)';
            app.StartCapturingSARCommandButton = uibutton(app.SARDataCapturingDiscreteCapturingPanel, 'push');
            app.StartCapturingSARCommandButton.ButtonPushedFcn = createCallbackFcn(app, @StartCapturingSARCommandButtonPushed, true);
            app.StartCapturingSARCommandButton.Text = 'Start Capturing';
            app.HorizontalMovementforEachStepmmEditFieldLabel = uilabel(app.SARDataCapturingDiscreteCapturingPanel);
            app.HorizontalMovementforEachStepmmEditFieldLabel.Text = 'Horizontal Movement for Each Step (mm)';
            app.HorizontalMovementforEachStepmmEditField = uieditfield(app.SARDataCapturingDiscreteCapturingPanel, 'numeric');
            app.WaitTimeBetweenStepssEditField_2Label = uilabel(app.SARDataCapturingDiscreteCapturingPanel);
            app.WaitTimeBetweenStepssEditField_2Label.Text = 'Wait Time Between Steps (s)';
            app.WaitTimeBetweenStepsSAREditField = uieditfield(app.SARDataCapturingDiscreteCapturingPanel, 'numeric');
            app.WaitTimeBetweenStepsSAREditField.Value = 1;
            app.RadarTypeLabel = uilabel(app.SARDataCapturingDiscreteCapturingPanel);
            app.RadarTypeLabel.Text = 'Radar Type';
            app.RadarTypeSwitchSAR = uiswitch(app.SARDataCapturingDiscreteCapturingPanel, 'slider');
            app.RadarTypeSwitchSAR.Items = {'TI', 'INRAS'};
            app.RadarTypeSwitchSAR.Value = 'TI';
            app.ResetMovementSARCommandButton = uibutton(app.SARDataCapturingDiscreteCapturingPanel, 'push');
            app.ResetMovementSARCommandButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMovementSARCommandButtonPushed, true);
            app.ResetMovementSARCommandButton.Text = 'Reset';
            app.TotalMovementTextArea_2Label = uilabel(app.SARDataCapturingDiscreteCapturingPanel);
            app.TotalMovementTextArea_2Label.Text = 'Total Movement';
            app.TotalMovementSARTextArea = uitextarea(app.SARDataCapturingDiscreteCapturingPanel);
            app.StatusScreenLabel_2 = uilabel(app.SARDataCapturingDiscreteCapturingPanel);
            app.StatusScreenLabel_2.Text = 'Status Screen';
            app.StatusScreenSARTextArea = uitextarea(app.SARDataCapturingDiscreteCapturingPanel);
            app.StopCapturingSARCommandButton = uibutton(app.SARDataCapturingDiscreteCapturingPanel, 'push');
            app.StopCapturingSARCommandButton.ButtonPushedFcn = createCallbackFcn(app, @StopCapturingSARCommandButtonPushed, true);
            app.StopCapturingSARCommandButton.Text = 'Stop Capturing';
            app.NumberofStepsatHorizontalAxisSpinnerLabel = uilabel(app.SARDataCapturingDiscreteCapturingPanel);
            app.NumberofStepsatHorizontalAxisSpinnerLabel.Text = 'Number of Steps at Horizontal Axis';
            app.NumberofStepsatHorizontalAxisSpinner = uispinner(app.SARDataCapturingDiscreteCapturingPanel);
            app.NumberofStepsatHorizontalAxisSpinner.Value = 1;
            app.NumberofStepsatVerticalAxisSpinnerLabel = uilabel(app.SARDataCapturingDiscreteCapturingPanel);
            app.NumberofStepsatVerticalAxisSpinnerLabel.Text = 'Number of Steps at Vertical Axis';
            app.NumberofStepsatVerticalAxisSpinner = uispinner(app.SARDataCapturingDiscreteCapturingPanel);
            app.NumberofStepsatVerticalAxisSpinner.Value = 1;
            app.VerticalMovementScenariommEditFieldLabel = uilabel(app.SARDataCapturingDiscreteCapturingPanel);
            app.VerticalMovementScenariommEditFieldLabel.Text = 'Vertical Movement Scenario (mm)';
            app.VerticalMovementScenariommEditField = uieditfield(app.SARDataCapturingDiscreteCapturingPanel, 'text');
            app.VerticalMovementScenariommEditField.Value = '0';
            app.Blankbetweenvalues0919Label = uilabel(app.SARDataCapturingDiscreteCapturingPanel);
            app.Blankbetweenvalues0919Label.Text = 'Blank between values (0.9 1.9)';
            app.ChecklistLabel_3 = uilabel(app.SARDataCapturingDiscreteCapturingPanel);
            app.ChecklistLabel_3.Text = 'Checklist:';
            app.ConnecttoActuatorLabel_3 = uilabel(app.SARDataCapturingDiscreteCapturingPanel);
            app.ConnecttoActuatorLabel_3.Text = '1- Connect to Actuator';
            app.ConnecttoRadarLabel_3 = uilabel(app.SARDataCapturingDiscreteCapturingPanel);
            app.ConnecttoRadarLabel_3.Text = '2-Connect to Radar';
            app.ConfigureRadarStudioandHSDCLabel_3 = uilabel(app.SARDataCapturingDiscreteCapturingPanel);
            app.ConfigureRadarStudioandHSDCLabel_3.Text = '4-Configure Radar Studio and HSDC';
            app.SetRPMLabel_2 = uilabel(app.SARDataCapturingDiscreteCapturingPanel);
            app.SetRPMLabel_2.Text = '3-Set RPM';
            app.SARScenarioContinuousTab = uitab(app.TabGroup);
            app.SARScenarioContinuousTab.Title = 'SAR Scenario (Continuous)';
            app.SARDataCapturingContinuousCapturingPanel = uipanel(app.SARScenarioContinuousTab);
            app.SARDataCapturingContinuousCapturingPanel.Title = 'SAR Data Capturing (Continuous Capturing)';
            app.StartCapturingContSARCommandButton = uibutton(app.SARDataCapturingContinuousCapturingPanel, 'push');
            app.StartCapturingContSARCommandButton.ButtonPushedFcn = createCallbackFcn(app, @StartCapturingContSARCommandButtonPushed, true);
            app.StartCapturingContSARCommandButton.Text = 'Start Capturing';
            app.HorizontalScanSizemmLabel = uilabel(app.SARDataCapturingContinuousCapturingPanel);
            app.HorizontalScanSizemmLabel.Text = 'Horizontal Scan Size (mm)';
            app.HorizontalScanSizemmContSAREditField = uieditfield(app.SARDataCapturingContinuousCapturingPanel, 'numeric');
            app.HorizontalScanSizemmContSAREditField.Value = 200;
            app.TimeBeforeCapturingsifTriggerFirstLabel = uilabel(app.SARDataCapturingContinuousCapturingPanel);
            app.TimeBeforeCapturingsifTriggerFirstLabel.Text = 'Time Before Capturing (s) ((-) if Trigger First)';
            app.WaitTimeBeforeCapturingContSAREditField = uieditfield(app.SARDataCapturingContinuousCapturingPanel, 'numeric');
            app.WaitTimeBeforeCapturingContSAREditField.Value = -1;
            app.ResetMovementContSARCommandButton = uibutton(app.SARDataCapturingContinuousCapturingPanel, 'push');
            app.ResetMovementContSARCommandButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMovementContSARCommandButtonPushed, true);
            app.ResetMovementContSARCommandButton.Text = 'Reset';
            app.TotalMovementTextArea_2Label_2 = uilabel(app.SARDataCapturingContinuousCapturingPanel);
            app.TotalMovementTextArea_2Label_2.Text = 'Total Movement';
            app.TotalMovementContSARTextArea = uitextarea(app.SARDataCapturingContinuousCapturingPanel);
            app.StatusScreenLabel = uilabel(app.SARDataCapturingContinuousCapturingPanel);
            app.StatusScreenLabel.Text = 'Status Screen';
            app.StatusScreenContSARTextArea = uitextarea(app.SARDataCapturingContinuousCapturingPanel);
            app.StopCapturingContSARCommandButton = uibutton(app.SARDataCapturingContinuousCapturingPanel, 'push');
            app.StopCapturingContSARCommandButton.ButtonPushedFcn = createCallbackFcn(app, @StopCapturingContSARCommandButtonPushed, true);
            app.StopCapturingContSARCommandButton.Text = 'Stop Capturing';
            app.NumberofStepsatVerticalScanLabel = uilabel(app.SARDataCapturingContinuousCapturingPanel);
            app.NumberofStepsatVerticalScanLabel.Text = 'Number of Steps at Vertical Scan';
            app.VerticalScanStepsContSARSpinner = uispinner(app.SARDataCapturingContinuousCapturingPanel);
            app.VerticalScanStepsContSARSpinner.Value = 30;
            app.Blankbetweenvalues0919ContSARLabel = uilabel(app.SARDataCapturingContinuousCapturingPanel);
            app.Blankbetweenvalues0919ContSARLabel.Text = 'Blank between values (0.9 1.9)';
            app.FramePeriodicitymsLabel = uilabel(app.SARDataCapturingContinuousCapturingPanel);
            app.FramePeriodicitymsLabel.Text = 'Frame Periodicity (ms)';
            app.FramePeriodicitymsContSAREditField = uieditfield(app.SARDataCapturingContinuousCapturingPanel, 'numeric');
            app.FramePeriodicitymsContSAREditField.Value = 10;
            app.NumberofFramesEditFieldLabel = uilabel(app.SARDataCapturingContinuousCapturingPanel);
            app.NumberofFramesEditFieldLabel.Text = 'Number of Frames';
            app.NumberofFramesContSAREditField = uieditfield(app.SARDataCapturingContinuousCapturingPanel, 'numeric');
            app.NumberofFramesContSAREditField.Value = 288;
            app.RecommendHorizontalScanSizeButtonContSAR = uibutton(app.SARDataCapturingContinuousCapturingPanel, 'push');
            app.RecommendHorizontalScanSizeButtonContSAR.ButtonPushedFcn = createCallbackFcn(app, @RecommendHorizontalScanSizeButtonContSARPushed, true);
            app.RecommendHorizontalScanSizeButtonContSAR.Text = 'Recommend';
            app.RecommendNumberOfFramesButtonContSAR = uibutton(app.SARDataCapturingContinuousCapturingPanel, 'push');
            app.RecommendNumberOfFramesButtonContSAR.ButtonPushedFcn = createCallbackFcn(app, @RecommendNumberOfFramesButtonContSARPushed, true);
            app.RecommendNumberOfFramesButtonContSAR.Text = 'Recommend';
            app.ChecklistLabel_5 = uilabel(app.SARDataCapturingContinuousCapturingPanel);
            app.ChecklistLabel_5.Text = 'Checklist:';
            app.ConnecttoActuatorLabel_5 = uilabel(app.SARDataCapturingContinuousCapturingPanel);
            app.ConnecttoActuatorLabel_5.Text = '1- Connect to Actuator';
            app.ConnecttoRadarLabel_5 = uilabel(app.SARDataCapturingContinuousCapturingPanel);
            app.ConnecttoRadarLabel_5.Text = '2-Connect to Radar';
            app.ConfigureRadarStudioandHSDCLabel_5 = uilabel(app.SARDataCapturingContinuousCapturingPanel);
            app.ConfigureRadarStudioandHSDCLabel_5.Text = '4-Configure Radar Studio and HSDC';
            app.SetSpeedLabel = uilabel(app.SARDataCapturingContinuousCapturingPanel);
            app.SetSpeedLabel.Text = '3-Set Speed';
            app.LambdaSpacingButton = uibutton(app.SARDataCapturingContinuousCapturingPanel, 'push');
            app.LambdaSpacingButton.ButtonPushedFcn = createCallbackFcn(app, @LambdaSpacingButtonPushed, true);
            app.LambdaSpacingButton.Text = '2 Lambda Spacing';
            app.VerticalStepSizemmEditFieldLabel = uilabel(app.SARDataCapturingContinuousCapturingPanel);
            app.VerticalStepSizemmEditFieldLabel.Text = 'Vertical Step Size (mm)';
            app.VerticalStepSizemmEditField = uieditfield(app.SARDataCapturingContinuousCapturingPanel, 'numeric');
            app.VerticalStepSizemmEditField.Limits = [0 Inf];
            app.VerticalStepSizemmEditField.Value = 7.57454;
            app.BackandForthScanningCheckBox = uicheckbox(app.SARDataCapturingContinuousCapturingPanel);
            app.BackandForthScanningCheckBox.Text = 'Back and Forth Scanning';
            app.BackandForthScanningCheckBox.Value = true;
            app.LargeScannerCheckBox = uicheckbox(app.SARDataCapturingContinuousCapturingPanel);
            app.LargeScannerCheckBox.Text = 'Large Scanner';
            app.RotatorTab = uitab(app.TabGroup);
            app.RotatorTab.Title = 'Rotator';
            app.DegtoRotateEditFieldLabel = uilabel(app.RotatorTab);
            app.DegtoRotateEditFieldLabel.Text = 'Deg to Rotate';
            app.DegtoRotateEditField = uieditfield(app.RotatorTab, 'numeric');
            app.RotatorTextAreaLabel = uilabel(app.RotatorTab);
            app.RotatorTextAreaLabel.Text = 'Rotator';
            app.RotatorTextArea = uitextarea(app.RotatorTab);
            app.RotateButton = uibutton(app.RotatorTab, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.RotationSpeeddegsEditFieldLabel = uilabel(app.RotatorTab);
            app.RotationSpeeddegsEditFieldLabel.Text = 'Rotation Speed (deg/s)';
            app.RotationSpeeddegsEditField = uieditfield(app.RotatorTab, 'numeric');
            app.RotationSpeeddegsEditField.Limits = [0 90];
            app.RotationSpeeddegsEditField.Value = 20;
            app.RotationSARTab = uitab(app.TabGroup);
            app.RotationSARTab.Title = 'Rotation SAR';
            app.StartCapturingRotSARCommandButton = uibutton(app.RotationSARTab, 'push');
            app.StartCapturingRotSARCommandButton.ButtonPushedFcn = createCallbackFcn(app, @StartCapturingRotSARCommandButtonPushed, true);
            app.StartCapturingRotSARCommandButton.Text = 'Start Capturing';
            app.StopCapturingRotSARCommandButton = uibutton(app.RotationSARTab, 'push');
            app.StopCapturingRotSARCommandButton.Text = 'Stop Capturing';
            app.StartingHorizontalPositionEditFieldLabel = uilabel(app.RotationSARTab);
            app.StartingHorizontalPositionEditFieldLabel.Text = 'Starting Horizontal Position';
            app.StartingHorizontalPositionEditField = uieditfield(app.RotationSARTab, 'numeric');
            app.StartingVerticalPositionEditFieldLabel = uilabel(app.RotationSARTab);
            app.StartingVerticalPositionEditFieldLabel.Text = 'Starting Vertical Position';
            app.StartingVerticalPositionEditField = uieditfield(app.RotationSARTab, 'numeric');
            app.StartingVerticalPositionEditField.Value = 150;
            app.RotationStepSizedegEditFieldLabel = uilabel(app.RotationSARTab);
            app.RotationStepSizedegEditFieldLabel.Text = 'Rotation Step Size (deg)';
            app.RotationStepSizedegEditField = uieditfield(app.RotationSARTab, 'numeric');
            app.RotationStepSizedegEditField.Value = 45;
            app.NumberofRotationStepsEditFieldLabel = uilabel(app.RotationSARTab);
            app.NumberofRotationStepsEditFieldLabel.Text = 'Number of Rotation Steps';
            app.NumberofRotationStepsEditField = uieditfield(app.RotationSARTab, 'numeric');
            app.NumberofRotationStepsEditField.Value = 8;
            app.RotationSARTextAreaLabel = uilabel(app.RotationSARTab);
            app.RotationSARTextAreaLabel.Text = 'Rotation SAR';
            app.RotationSARTextArea = uitextarea(app.RotationSARTab);
            app.EmergencyStopAcuatorButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopAcuatorButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStopAcuatorButtonPushed, true);
            app.EmergencyStopAcuatorButton.Text = 'Emergency Stop Acuator';
            app.TotalHorizontalMovementmmEditFieldLabel = uilabel(app.UIFigure);
            app.TotalHorizontalMovementmmEditFieldLabel.Text = 'Total Horizontal Movement (mm)';
            app.TotalHorizontalMovementmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalHorizontalMovementmmEditField.Editable = 'off';
            app.TotalVerticalMovementmmEditFieldLabel = uilabel(app.UIFigure);
            app.TotalVerticalMovementmmEditFieldLabel.Text = 'Total Vertical Movement (mm)';
            app.TotalVerticalMovementmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalVerticalMovementmmEditField.Editable = 'off';
            app.TotalRotationMovementdegEditFieldLabel = uilabel(app.UIFigure);
            app.TotalRotationMovementdegEditFieldLabel.Text = 'Total Rotation Movement (deg)';
            app.TotalRotationMovementdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalRotationMovementdegEditField.Editable = 'off';
            app.ScanNameEditFieldLabel = uilabel(app.UIFigure);
            app.ScanNameEditFieldLabel.Text = 'Scan Name';
            app.ScanNameEditField = uieditfield(app.UIFigure, 'text');
            app.ScanNameEditField.ValueChangedFcn = createCallbackFcn(app, @ScanNameEditFieldValueChanged, true);
            app.ScanNameEditFieldLabel_2 = uilabel(app.UIFigure);
            app.ScanNameEditFieldLabel_2.Text = '.bin';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SAR_GUI_Josiah

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end